classdef BeltApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BeltAppUIFigure                matlab.ui.Figure
        Toolbar                        matlab.ui.container.Toolbar
        AddButton                      matlab.ui.container.toolbar.PushTool
        DeleteEntryButton              matlab.ui.container.toolbar.PushTool
        MergeButton                    matlab.ui.container.toolbar.PushTool
        UndoButton                     matlab.ui.container.toolbar.PushTool
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        TimeScrubberPanel              matlab.ui.container.Panel
        TimeScrubberPlus               matlab.ui.control.Button
        TimeScrubberMinus              matlab.ui.control.Button
        TimeScrubberValue              matlab.ui.control.EditField
        secEditFieldLabel              matlab.ui.control.Label
        ARTIFACTORNOARTIFACTPanel      matlab.ui.container.Panel
        ArtifactYesButton              matlab.ui.control.Button
        ArtifactNoButton               matlab.ui.control.Button
        InfoPanel                      matlab.ui.container.Panel
        AllButton                      matlab.ui.control.Button
        SelectedListLabel              matlab.ui.control.Label
        FocusButton                    matlab.ui.control.Button
        NextButton                     matlab.ui.control.Button
        PreviousButton                 matlab.ui.control.Button
        SelectedLabel                  matlab.ui.control.Label
        SelectedArtifactsLabel         matlab.ui.control.Label
        ArtifactLabel                  matlab.ui.control.Label
        ArtifactsLabel                 matlab.ui.control.Label
        ArtifactDurationPanel          matlab.ui.container.Panel
        Label                          matlab.ui.control.Label
        TimeTooltip                    matlab.ui.control.TextArea
        ShowtimevalueatcursorCheckBox  matlab.ui.control.CheckBox
        ArtifactEndValue               matlab.ui.control.NumericEditField
        EndEditFieldLabel              matlab.ui.control.Label
        ArtifactStartValue             matlab.ui.control.NumericEditField
        StartEditFieldLabel            matlab.ui.control.Label
        Select2artifactstomergeontheouterstartendLabel_2  matlab.ui.control.Label
        ConclusionPanel                matlab.ui.container.Panel
        AcceptButton                   matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
        SolutionOptionsPanel           matlab.ui.container.Panel
        SolutionsDropDown              matlab.ui.control.DropDown
        SolutionLabel                  matlab.ui.control.Label
        SolutionsCustomValue           matlab.ui.control.NumericEditField
        customvalueEditFieldLabel      matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        CurrentStateLabel              matlab.ui.control.Label
        OriginalDataAxes               matlab.ui.control.UIAxes
        SolutionAxes                   matlab.ui.control.UIAxes
        ArtefactValidationAxes         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = public)
        time % float value for time
        timeResolution % distance between two timepoints
        original % array of the original data
        validation % array of the validation data
        solution % array of the solution data
        selectedartifacts % int of amount of selected artifacts
        visibleartifacts % array of 1s (true) and 0s (false) to keep track of which artifacts are visible
        focusMinTime % the lowest time value of a focussed window
        focusMaxTime % the highest time value of a focussed window
        artifacts % struct with all artifacts ('start','end','solution','custom','selected')
        artifactobjects % rectangles mapped on the axis
        originalobject % the original data plot, later used for handling the brush
        addartifactapp % connection to the add artifact window
        closeapplication = 0; % boolean value for the main application to return when set to true
        lastselected = 0; % index of the last selected artifact
        mode = 'focus'; % whether the current mode is in 'focus'  or 'all', determining the current visualization
        history % all previous artifacts structs. Every time a change is made, it will be appended to this list. Once the undo button is pressed, it will remove the latest entry, and overwrite artifacts with the current last one.
        prepostduration = 20;
        drawvalidationdata = 1;
        ToolTip2 % Shows the time value of the cursor position
        ArtifactDragging = struct('BeingDragged',false,'Index',0, 'NearBorder', false, 'LeftBorder',true, 'BorderChanged', false, 'DurationChanged', false); % Keep track of artifact border being dragged
    end

    properties (Access = private)
    end

    methods (Access = public)
        %% CALLBACKS
        % Callback when artifact is clicked on
        function ClickArtifact(app,object,eventdata)
            % if the cursor is over an artifact border
            if app.ArtifactDragging.NearBorder
                % start dragging the artifact border
                app.ArtifactDragging.BeingDragged = true;
            else
                % (de)select the artifact that is clicked on
                startpos = object.Position(1);
                endpos = object.Position(1) + object.Position(3);
                for i=1:length(app.artifacts)
                    if startpos == app.artifacts(i).starttime && endpos == app.artifacts(i).endtime
                        ArtifactInteraction(app,i);
                        break;
                    end
                end
            end
        end

        % Callback then original data axis is clicked on
        function ClickAxes(app,object,eventdata)
            if app.ArtifactDragging.NearBorder
                % start dragging the artifact border
                app.ArtifactDragging.BeingDragged = true;
            else
                % deselect all artifacts
                app.DisableArtifactSelections();
            end
        end

        %% INTERACTIVITY
        % Interact with an Artifact
        function ArtifactInteraction(app,artifactID)
            if app.artifacts(artifactID).selected == 0
                % an artifact that was not selected, is now selected
                % deselect the currently selected one, to keep only one selected at all times
                currently_selected = find([app.artifacts(:).selected]);
                if currently_selected>0
                    app.artifacts(currently_selected).selected = 0;
                end
                app.artifacts(artifactID).selected = 1;
            else
                app.artifacts(artifactID).selected = 0;
            end
            app.lastselected = artifactID;
            app.CalculateSelectedArtifacts();
            app.RedrawArtifacts();
        end

        % Deselect all artifacts
        function DisableArtifactSelections(app)
            for i=1:length(app.artifacts)
                app.artifacts(i).selected = 0;
            end
            app.CalculateSelectedArtifacts();
            app.RedrawArtifacts();
        end

        % Delete all selected artifacts
        function RemoveAction(app)
            for i=length(app.artifacts):-1:1
                if app.artifacts(i).selected == 1
                    app.artifacts(i)=[];
                end
            end

            %if strcmp(app.mode,'focus')
                app.SelectClosestTo(app.lastselected);
            %end

            app.AddActionToHistory;

            mintime = min(app.time);
            maxtime = max(app.time);
            app.SetVisibleArtifacts(mintime, maxtime);
            app.CalculateVisibleSolution();
            app.CalculateSelectedArtifacts();
            app.RedrawSolution();
            app.RedrawArtifacts();

            if strcmp(app.mode, 'focus')
                % pause for 1 second to give the user confirmation that the
                % artifact was removed. Then move to the next artifact
                pause(0.5);
                app.FocusOnSelection();
            end
            app.SetActiveUIElements();
        end

        function SelectClosestTo(app,index)
            l = length(app.artifacts);
            if l == 0
                return
            elseif l > index
                app.artifacts(index).selected = 1;
                app.lastselected = index;
            else
                app.artifacts(l).selected = 1;
                app.lastselected = l;
            end
        end

        % Merge selected artifacts if multiple are selected using the first starttime, last end, and first artifactID Solution
        function MergeAction(app)
            selected = app.GetSelectedArtifacts();
            maxend = 0;
            minstart = max(app.time);

            for i=1:length(selected)
                thisend = app.artifacts(selected(i)).endtime;
                thisstart = app.artifacts(selected(i)).starttime;
                if thisend > maxend
                    maxend = thisend;
                end
                if thisstart < minstart
                    minstart = thisstart;
                end
            end

            app.artifacts(selected(1)).endtime = maxend;
            app.artifacts(selected(1)).starttime = minstart;

            for i=length(selected):-1:2
                app.artifacts(selected(i))=[];
            end

            app.AddActionToHistory;

            app.CalculateVisibleSolution();
            app.RedrawSolution();
            app.RedrawArtifacts();
            app.SetActiveUIElements();
        end

        % Reverts the current artifacts to the previous state. Only
        % considers remove, add, merge, solution and duration changes.
        % Selection is not taken into account right now
        function UndoAction(app)
            if length(app.history) > 1
                app.artifacts = app.history(length(app.history)-1).artifacts;
                app.history(length(app.history)) = [];
            else
                app.UndoButton.Enable = 0;
            end

            app.CalculateSolution();
            app.RedrawSolution();
            app.RedrawArtifacts();
            app.SetActiveUIElements();
        end
        
        % add current state to the history list and enable the undo button
        function AddActionToHistory(app)
            app.history(length(app.history)+1).artifacts = app.artifacts;
            app.UndoButton.Enable = true;
        end


        % Change the duration of the selected artifact
        function DurationAction(app)
            for i=1:length(app.artifacts)
                if app.artifacts(i).selected == 1
                    app.artifacts(i).starttime = app.ArtifactStartValue.Value;
                    app.artifacts(i).endtime   = app.ArtifactEndValue.Value;
                end
            end

            % update the history only when dragging stops, not on every move
            % app.AddActionToHistory;

            app.CalculateVisibleSolution();
            app.RedrawSolution();
            app.RedrawArtifacts();
        end

        % Disable add action, and open window for creating new artifact
        function NewArtifactAction(app)
            if isprop(app.originalobject,'BrushData') && any (app.originalobject.BrushData)
                selected = app.originalobject.BrushData;
                range = find(selected ==1);
                starttime = app.time(min(range));
                endtime = app.time(max(range));
                
                app.originalobject.BrushData = 0;

                app.ReturnNewArtifactAction(starttime, endtime);
            end
        end

        % Catch the new artifact action and update current
        function ReturnNewArtifactAction(app,starttime,endtime)
            artifact = struct('starttime',starttime,'endtime',endtime,'solution','linear','custom',1,'selected',1);
            if isempty(app.artifacts)
                app.artifacts = artifact;
            else
                app.artifacts(length(app.artifacts)+1) = artifact;
            end
            app.SortArtifacts();

            app.AddActionToHistory;

            app.FocusOnSelection();
            app.CalculateSolution();
            app.RedrawSolution();
            app.RedrawArtifacts();
            app.CalculateSelectedArtifacts();
        end

        % Change solution on all selected artifacts
        function ChangeSolution(app,solution)
            for i=1:length(app.artifacts)
                if app.artifacts(i).selected == 1
                    app.artifacts(i).solution=solution;
                    if strcmp(solution,'custom')
                        app.artifacts(i).custom=app.SolutionsCustomValue.Value;
                    end
                end
            end

            app.AddActionToHistory;

            app.CalculateVisibleSolution();
            app.RedrawSolution();
        end

        % Select next artifact
        function SelectNextArtifact(app)
            app.DeselectAllArtifacts();

            if app.lastselected == length(app.artifacts)
                app.lastselected = 1;
                app.ShowAllData();
            else
                app.lastselected = app.lastselected+1;
                app.artifacts(app.lastselected).selected = 1;
                if strcmp(app.mode,'focus')
                    app.FocusOnSelection();
                else
                    app.ShowAllData();
                end
            end

            app.SetActiveUIElements();
            app.RedrawArtifacts();
            app.CalculateSelectedArtifacts();
        end

        % Select previous artifact
        function SelectPreviousArtifact(app)
            app.DeselectAllArtifacts();
            app.lastselected = app.lastselected-1;
            app.artifacts(app.lastselected).selected = 1;

            if strcmp(app.mode,'focus')
                app.FocusOnSelection();
            else
                app.ShowAllData();
            end

            app.SetActiveUIElements();
            app.RedrawArtifacts();
            app.CalculateSelectedArtifacts();
        end

        % Focus on the selected artifact areas
        function FocusOnSelection(app)
            mintime = min(app.time);
            maxtime = max(app.time);
            selected = app.GetSelectedArtifacts();
            if isempty(selected)
                return;
            end

            for i=1:length(selected)
                if app.artifacts(selected(i)).starttime > mintime
                    mintime = app.artifacts(selected(i)).starttime - app.prepostduration;
                end
                if app.artifacts(selected(i)).endtime < maxtime
                    maxtime = app.artifacts(selected(i)).endtime + app.prepostduration;
                end
            end
            mintime = clamp(mintime,min(app.time),max(app.time));
            maxtime = clamp(maxtime,min(app.time),max(app.time));

            % determine which artifacts are within the focus interval
            app.SetVisibleArtifacts(mintime, maxtime);
            app.FocusOnArea(mintime,maxtime);
            app.mode = 'focus';
        end

        function SetVisibleArtifacts(app, mintime, maxtime)
            % intialize array of zeros to set visible to false for each artifact
            visible_artifacts = zeros(1, length(app.artifacts));
            % then check for each whether the time ofthe artifact falls
            % within the specified time window
            for artifact_i=1:length(app.artifacts)
                if (app.artifacts(artifact_i).starttime > mintime && app.artifacts(artifact_i).starttime < maxtime) ||...
                        (app.artifacts(artifact_i).endtime > mintime && app.artifacts(artifact_i).endtime < maxtime)
                    visible_artifacts(artifact_i) = 1;
                end
            end
            app.visibleartifacts = visible_artifacts;
        end

        % Show the entire data signal
        function ShowAllData(app)
            app.mode = 'all';
            mintime = min(app.time);
            maxtime = max(app.time);
            
            % all artifacts are visible
            app.SetVisibleArtifacts(mintime, maxtime);
            app.RedrawArtifacts();
            app.FocusOnArea(mintime,maxtime);
        end

        % Deselect all artifacts
        function DeselectAllArtifacts(app)
            for i=1:length(app.artifacts)
                app.artifacts(i).selected = 0;
            end
        end

        % Select all artifacts
        function SelectAllArtifacts(app)
            for i=1:length(app.artifacts)
                app.artifacts(i).selected = 1;
            end
        end

        %% VISUALS
        % Reset the current visuals, and draw the latest visual state
        function PlotVisuals(app)
            cla(app.ArtefactValidationAxes)
            if app.drawvalidationdata == 1
                cla(app.OriginalDataAxes);
            end
            cla(app.SolutionAxes);
            app.PlotArtifacts();
            hold(app.ArtefactValidationAxes,'on');
            app.PlotAxisData();
            hold(app.ArtefactValidationAxes,'off');
        end

        %Plot the latest data on the original / validation / solution axes
        function PlotAxisData(app)
            app.originalobject = plot(app.ArtefactValidationAxes,app.time,app.original);
            set(app.ArtefactValidationAxes,'ButtonDownFcn',@app.ClickAxes);
            if app.drawvalidationdata == 1
                plot(app.OriginalDataAxes,app.time,app.validation)
            end
            plot(app.SolutionAxes,app.time,app.solution)
        end

        %Plot all artifacts on the original axes
        function PlotArtifacts(app)

            for i=find(app.visibleartifacts)
                startpos = app.artifacts(i).starttime;
                endpos = app.artifacts(i).endtime;
                color = [1 .8 .8 0.5];
                if app.artifacts(i).selected == 1
                    color = [.4 .8 .5 0.5];
                end

                app.artifactobjects(i) = rectangle(app.ArtefactValidationAxes,'Position',[startpos 0 endpos-startpos max(app.original)],"FaceColor",color);
                set(app.artifactobjects(i),'ButtonDownFcn',@app.ClickArtifact);
            end

            app.ArtifactLabel.Text = mat2str(length(app.artifacts));
        end

        % Only recreate the artifact visuals
        function RedrawArtifacts(app)
            delete(app.artifactobjects);
            app.artifactobjects=[];
            app.PlotArtifacts();
        end

        % Only recreate the solution visual
        function RedrawSolution(app)
            cla(app.SolutionAxes);
            plot(app.SolutionAxes,app.time,app.solution);
        end

        % Set the visuals to focus on a specific area
        function FocusOnArea(app,minx,maxx)

            miny = min(app.RetrieveSection(minx,maxx));
            maxy = max(app.RetrieveSection(minx,maxx));

            app.focusMinTime = minx;
            app.focusMaxTime = maxx;

            set(app.ArtefactValidationAxes,'XLim',[minx maxx],'YLim',[miny maxy]);
            if app.drawvalidationdata == 1
                set(app.OriginalDataAxes,'XLim',[minx maxx]);
            end
            set(app.SolutionAxes,'XLim',[minx maxx]);
        end

        %% UI ELEMENTS

        % Set the enabled UI elements based on the selected artifacts
        function SetActiveUIElements(app)
            app.SolutionsDropDown.Enable = 0;
            app.DeleteEntryButton.Enable = 0;
            app.ArtifactStartValue.Enable = 0;
            app.ArtifactStartValue.Value = 0;
            app.ArtifactEndValue.Enable = 0;
            app.ArtifactEndValue.Value = 0;
            app.MergeButton.Enable = 0;
            app.FocusButton.Enable = 0;
            app.PreviousButton.Enable = 0;
            app.NextButton.Enable = 0;
            app.ArtifactYesButton.Enable = 0;
            app.ArtifactNoButton.Enable = 0;
            app.UndoButton.Enable = 0;

            if (length(app.history)) > 1
                app.UndoButton.Enable = 1;
            end
            if ~isempty(app.artifacts)
                app.FocusButton.Enable = 1;
                app.PreviousButton.Enable = 1;
                app.NextButton.Enable = 1;
            end

            if (app.selectedartifacts > 0)
                app.SolutionsDropDown.Enable = 1;
                app.DeleteEntryButton.Enable = 1;
            end
            if (app.selectedartifacts == 1)
                app.ArtifactStartValue.Enable = 1;
                app.ArtifactEndValue.Enable = 1;
                app.ArtifactYesButton.Enable = 1;
                app.ArtifactNoButton.Enable = 1;
                for i=1:length(app.artifacts)
                    if app.artifacts(i).selected == 1
                        app.ArtifactStartValue.Value = app.artifacts(i).starttime;
                        app.ArtifactEndValue.Value = app.artifacts(i).endtime;
                        app.SolutionsDropDown.Value = app.artifacts(i).solution;
                    end
                end
            end
            if (app.selectedartifacts > 1)
                app.MergeButton.Enable = 1;
            end
            if (app.lastselected == length(app.artifacts))
                app.NextButton.Enable = 0;
            end
            if (app.lastselected <= 1)
                app.PreviousButton.Enable = 0;
            end
        end

        %% CALCULATIONS

        function out = FindArtifactID(app,starttime,endtime)
            out = 0;
            for i=1:length(app.artifacts)
                if app.artifacts(i).starttime == starttime && app.artifacts(i).endtime == endtime
                    out = i;
                end
            end
        end

        % Sort all artifacts based on their starttime
        function SortArtifacts(app)
            if ~isempty(app.artifacts)
                T=struct2table(app.artifacts);
                sorT = sortrows(T,'starttime');
                app.artifacts = table2struct(sorT);
            end
        end

        % get a list with the artifacts currently selected
        function out = GetSelectedArtifacts(app)
            out = [];
            for i=1:length(app.artifacts)
                if app.artifacts(i).selected == 1
                    out = [out;i];
                end
            end
        end

        % calculate how many artifacts there are in the application
        function CalculateSelectedArtifacts(app)
            app.selectedartifacts = 0;
            app.lastselected = 0;
            for i=1:length(app.artifacts)
                if app.artifacts(i).selected == 1
                    app.selectedartifacts = app.selectedartifacts+1;
                    app.lastselected = i;
                end
            end
            app.SetActiveUIElements();
            app.SelectedLabel.Text = mat2str(app.selectedartifacts);
            if app.selectedartifacts == 1
                app.SelectedListLabel.Text = mat2str(app.lastselected);                  
            else
                app.SelectedListLabel.Text = "NA";
            end
        end

        % Grab the original, and change all artifact ranges to their solution
        function CalculateSolution(app)
            % replace all artifact intervals with the chosen solution
            for i=1:length(app.artifacts)
                SetArtifactOutcome(app,app.artifacts(i));
            end
        end

        % calculate the solution of a single artifact
        function CalculateVisibleSolution(app)
            
            % start fresh with the original data
            minx = find(app.time==app.focusMinTime);
            maxx = find(app.time==app.focusMaxTime);
            app.solution(minx:maxx) = app.original(minx:maxx);

            % then replace visible artifact intervals with the chosen solution
            for i=find(app.visibleartifacts)
                SetArtifactOutcome(app,app.artifacts(i));
            end
        end

        % Get a section of the data from the original axis
        function out  = RetrieveSection(app,s,e)
            tRange = find(app.time>=s & app.time<=e);
            min(tRange);
            max(tRange);
            out= app.original(min(tRange):max(tRange));
        end

        % Get the solution to the artifact based on the artifact solution value
        function SetArtifactOutcome(app,artifact)

            startpos = find(app.time==artifact.starttime);
            endpos = find(app.time==artifact.endtime);

            artifactarray = app.original(startpos:endpos);

            if strcmp(artifact.solution,"linear")
                artifactarray = linspace(artifactarray(1),artifactarray(length(artifactarray)),length(artifactarray));
            end
            if strcmp(artifact.solution,"NaN")
                artifactarray = NaN(1, length(artifactarray));
            end
            if strcmp(artifact.solution,"startpos")
                artifactarray = artifactarray(1) * ones(1, length(artifactarray));
            end
            if strcmp(artifact.solution,"endpos")
                artifactarray = artifactarray(end) * ones(1, length(artifactarray));
            end
            if strcmp(artifact.solution,"custom")
                artifactarray = artifact.custom * ones(1, length(artifactarray));
            end
            if strcmp(artifact.solution,"spline")
                % the spline function generates a smooth line between points
                % to replace the artifact with a new curve
                % we need some data before and after to create the spline
                % here we use 2 datapoints to anker the spline to
                spline_ankers = 2;

                % create new start and end positions, 
                newstartpos = startpos - spline_ankers;
                newendpos   = endpos   + spline_ankers;

                % check whether these do not exceed the timeline of the data
                newstartpos = clamp(newstartpos, 1, length(app.time));
                newendpos = clamp(newendpos, 1, length(app.time));

                % determine the adjusted anker width for the spline
                spline_anker_start = startpos - newstartpos; % ?? what happens when this is 0??
                spline_anker_end   = newendpos - endpos;

                % get the orignal data for the artifact plus spline ankers
                x = app.time(newstartpos:newendpos);
                y = app.original(newstartpos:newendpos);

                % replace the artifact by NaNs, keep the spline ankers
                % Note that these are the original ankers, not the adjusted ones. So, 
                % if the ankers have been adjusted, now artifact data is used 
                % to generate the smooth line, which is not ideal.
                % y(spline_ankers+1:length(y)-spline_ankers) = NaN;
                x_ankers = x;
                x_ankers(spline_ankers+1:length(x)-spline_ankers) = [];
                y_ankers = y;
                y_ankers(spline_ankers+1:length(y)-spline_ankers) = [];

                % use spline() to generate a smooth line connecting the start and end ankers
                result = spline(x_ankers,y_ankers, x);

                % cut out the interval of the artifact
                artifactarray = result(spline_anker_start+1:length(y)-spline_anker_end);

            end
            app.solution(startpos:endpos) = artifactarray;
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data, cfg)
          
            %Make app fullscreen
            drawnow;
            % app.BeltAppUIFigure.WindowState = 'maximized';

            app.BeltAppUIFigure.Name = 'Artifact Correction App';

            % Show which participant's data this is
            if isfield(cfg, 'participant')
                app.CurrentStateLabel.Text = ['    Participant: ' cfg.participant];
            end

            %Set additional pre post time of datasignal shown around
            %focused artifact
            if isfield(cfg, 'artifactprepostvisualization')
                app.prepostduration = cfg.artifactprepostvisualization;
            end
            if isfield(cfg, 'time')
                app.time = cfg.time;
            else
                app.time = 0:length(data)-1;%linspace(0,length(app.original)-1,length(app.original));
            end
            app.timeResolution = app.time(2)-app.time(1);
            if isfield(cfg, 'validation')
                app.validation = cfg.validation;
            else
                app.validation = app.time;%;linspace(0,length(app.original)-1,length(app.original));
                app.OriginalDataAxes.Visible = "off";
                app.drawvalidationdata = 0;
                app.GridLayout2.RowHeight = ["0.25x","1x","0x","1x"];
            end
            if isfield(cfg, 'artifacts')
                if ~isempty(cfg.artifacts)
                    artifacts_new(length(cfg.artifacts)) = struct('starttime',0,'endtime',0,'solution','linear','custom',1,'selected',0);
                    for i=1:length(cfg.artifacts)
                        artifact = cfg.artifacts(i);
                        if ~isfield(artifact, 'solution')
                            artifact.solution = 'linear';
                        end
                        if ~isfield(artifact, 'custom')
                            artifact.custom = 1;
                        end
                        if ~isfield(artifact, 'selected')
                            artifact.selected = 0;
                        end
                        artifacts_new(i) = artifact;
                    end
                    app.artifacts = artifacts_new;
                    app.history(1).artifacts = app.artifacts;
                end
            end

            % Set necessary properties / variable
            app.original = data;
            app.solution = data;

            % Perform initial calculations
            app.CalculateSolution();

            % Set initial axes
            app.PlotVisuals();

            % Set UI Elements
            app.SetActiveUIElements();

            % Sort artifacts for linear selection option
            app.SortArtifacts();

            % Select the first artifact if there are any artifacts provided
            if ~isempty(app.artifacts)
                app.SelectNextArtifact();
            end

            % Select and focus on the first artifact
            app.FocusOnSelection();

        end

        % Callback function: AddButton
        function AddButtonPushed(app, event)
            app.NewArtifactAction();
        end

        % Callback function: MergeButton
        function MergeButtonPushed(app, event)
            app.MergeAction();
        end

        % Callback function: DeleteEntryButton
        function DeleteEntryButtonPushed(app, event)
            app.RemoveAction();
        end

        % Callback function
        function SolutionsListBoxValueChanged(app, event)
            value = app.SolutionsListBox.Value;
            app.ChangeSolution(value);
        end

        % Value changed function: SolutionsCustomValue
        function SolutionsCustomValueValueChanged(app, event)
            value = app.SolutionsCustomValue.Value;
            app.ChangeSolution('custom');
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.solution = app.original;
            app.closeapplication = 1;
        end

        % Button pushed function: AcceptButton
        function AcceptButtonPushed(app, event)
            app.closeapplication = 1;
        end

        % Close request function: BeltAppUIFigure
        function BeltAppUIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: ArtifactStartValue
        function ArtifactStartValueValueChanged(app, event)
            
            % get the value from the input box
            value = app.ArtifactStartValue.Value;

            % adjust this value to the closest match in the list of timepoints
            value = interp1(app.time,app.time,value,'nearest');
            app.ArtifactStartValue.Value = value;

            % update the duration and redraw
            app.DurationAction();

            % if this was not the result of a dragging action, update the history list
            if app.ArtifactDragging.BeingDragged == false
                app.AddActionToHistory;
            end
        end

        % Value changed function: ArtifactEndValue
        function ArtifactEndValueValueChanged(app, event)
            
            % get the value from the input box
            value = app.ArtifactEndValue.Value;

            % adjust this value to the closest match in the list of timepoints
            value = interp1(app.time,app.time,value,'nearest');
            app.ArtifactEndValue.Value = value;

            % update the duration and redraw
            app.DurationAction();

            % if this was not the result of a dragging action, update the history list
            if app.ArtifactDragging.BeingDragged == false
                app.AddActionToHistory;
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.SelectNextArtifact();
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            app.SelectPreviousArtifact();
        end

        % Button pushed function: FocusButton
        function FocusButtonPushed(app, event)
            app.FocusOnSelection();
        end

        % Button pushed function: AllButton
        function AllButtonPushed(app, event)
            app.ShowAllData();
        end

        % Button pushed function: ArtifactNoButton
        function ArtifactNoButtonPushed(app, event)
            app.RemoveAction();
        end

        % Button pushed function: ArtifactYesButton
        function ArtifactYesButtonPushed(app, event)
            app.SelectNextArtifact();
        end

        % Value changed function: SolutionsDropDown
        function SolutionsDropDownValueChanged(app, event)
            value = app.SolutionsDropDown.Value;
            app.ChangeSolution(value);
        end

        % Callback function: UndoButton
        function UndoButtonClicked(app, event)
            app.UndoAction();
        end

        % Button pushed function: TimeScrubberPlus
        function TimeScrubberPlusPushed(app, event)
            
        end

        % Button pushed function: TimeScrubberMinus
        function TimeScrubberMinusButtonPushed(app, event)
            
        end

        % Window button motion function: BeltAppUIFigure
        function BeltAppUIFigureWindowButtonMotion(app, event)
            % get the mouse coordinates
            cursorXY = app.BeltAppUIFigure.CurrentPoint; 
            cursorTime = app.ArtefactValidationAxes.CurrentPoint(1,1);

            % determine wheter mouse is over the artifact validation area
            isInAxes = cursorXY(1) >= (app.RightPanel.Position(1) + app.ArtefactValidationAxes.InnerPosition(1)) && ...
                cursorXY(1) <= (app.RightPanel.Position(1) + sum(app.ArtefactValidationAxes.InnerPosition([1,3]))) && ...
                cursorXY(2) >= app.ArtefactValidationAxes.InnerPosition(2) && ...
                cursorXY(2) <= sum(app.ArtefactValidationAxes.InnerPosition([2,4]));

            if isInAxes
                % if the "show time at cursor" checkbox is checked, place a
                % small textbox near the cursor to show the time value at the cursor
                if app.ShowtimevalueatcursorCheckBox.Value == 1
                    % show the time value tooltip
                    mouseX = app.BeltAppUIFigure.CurrentPoint(1, 1)+10;
                    mouseY = app.BeltAppUIFigure.CurrentPoint(1, 2)+10;
                    set(app.TimeTooltip, 'Value',...
                        sprintf('t=%.2f', app.ArtefactValidationAxes.CurrentPoint(1,1)));
                    set(app.TimeTooltip, 'Position', [mouseX, mouseY, 60, 25]);
                    set(app.TimeTooltip, 'Visible', true);
                    app.TimeTooltip.Parent =  app.BeltAppUIFigure;
                else
                    % hide the time value tooltip
                    set(app.TimeTooltip, 'Value', '')
                    set(app.TimeTooltip, 'Visible', false)
                end

                % if an artifact border is in the process of being dragged
                if app.ArtifactDragging.BeingDragged
                    if app.ArtifactDragging.LeftBorder
                        % update the starttime of the artifact
                        % check whether it is still smaller than the endtime
                        if (app.ArtifactEndValue.Value - cursorTime) > app.timeResolution
                            app.ArtifactStartValue.Value = cursorTime;
                            app.ArtifactStartValueValueChanged(app);
                            app.ArtifactDragging.DurationChanged = true;
                        end 
                    else
                        % update the endtime of the artifact
                        % check whether it is still larger than the starttime
                       if (cursorTime - app.ArtifactStartValue.Value) > app.timeResolution
                           app.ArtifactEndValue.Value = cursorTime;
                           app.ArtifactEndValueValueChanged(app);
                           app.ArtifactDragging.DurationChanged = true;
                      end
                    end
                
                else % if not currently being dragged

                    % test whether the cursor is near the border of the selected artifact interval
                    isNearBorder = false;
                    nearnessLimit = app.timeResolution;
                    % check which artifact is selected
                    selectedArtifactIndex = find([app.artifacts.selected]);

                    % check whether the cursor is near the borders of the selected artifact
                    if selectedArtifactIndex > 0
                        startpos = app.artifacts(selectedArtifactIndex).starttime; % left border
                        endpos = app.artifacts(selectedArtifactIndex).endtime; % right border
                        if abs(cursorTime - startpos) <= nearnessLimit
                            isNearBorder = true;
                            app.ArtifactDragging.LeftBorder = true;
                        elseif abs(cursorTime - endpos) <= nearnessLimit
                            isNearBorder = true;
                            app.ArtifactDragging.LeftBorder = false;
                        end
                        if isNearBorder
                            % change the cursor to the left-right dubbel arrow <->
                            % to indicate that the border can be dragged
                            app.BeltAppUIFigure.Pointer = "left";
                            % mark that cursor is near the border
                            app.ArtifactDragging.NearBorder = true;
                            % mark which artifact this is the border of
                            app.ArtifactDragging.Index = selectedArtifactIndex;
                        else
                            % change cursor back to the default arrow
                            app.BeltAppUIFigure.Pointer = "arrow";
                            % and mark that cursor is not near a border
                            app.ArtifactDragging.NearBorder = false;
                        end
                    end
                end
            else
                % make sure the pointer is an arrow when the cursor leaves
                % the artifact validation area
                app.BeltAppUIFigure.Pointer = "arrow";
                % and mark that cursor is not near a border
                app.ArtifactDragging.NearBorder = false; % TODO: this is a functionality choice, test whether this is optimal
            end

        end

        % Button down function: BeltAppUIFigure
        function BeltAppUIFigureButtonDown(app, event)
            disp('UIFigureButtonDown\n');
        end

        % Window button up function: BeltAppUIFigure
        function BeltAppUIFigureWindowButtonUp(app, event)
            app.ArtifactDragging.BeingDragged = false;
            if app.ArtifactDragging.DurationChanged == true
                app.AddActionToHistory;
                app.ArtifactDragging.DurationChanged = false;
            end

        end

        % Button down function: ArtefactValidationAxes
        function ArtefactValidationAxesButtonDown(app, event)
            % disp('ArtifactValidationAxesButtonDown\n');       
            % % test whether the cursor is near the border of an artifact interval
            % nearnessLimit = 0.2;
            % % check the borders of all artifacts
            % for i=1:length(app.artifacts)
            %     startpos = app.artifacts(i).starttime; % left border
            %     endpos = app.artifacts(i).endtime; % right border
            %     if abs(cursorTime - startpos) <= nearnessLimit
            %         % indicate that dragging has started
            %         app.ArtifactDragging.BeingDragged = true;
            %         app.ArtifactDragging.LeftBorder = true;
            %         app.ArtifactDragging.Index = i;
            %         break;
            %     elseif abs(cursorTime - startpos) <= nearnessLimit || abs(cursorTime - endpos) <= nearnessLimit
            %         % indicate that dragging has started
            %         app.ArtifactDragging.BeingDragged = true;
            %         app.ArtifactDragging.LeftBorder = false;
            %         app.ArtifactDragging.Index = i;
            %         break;
            %     end
            % end
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.BeltAppUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {589, 589};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.BeltAppUIFigure = uifigure('Visible', 'off');
            app.BeltAppUIFigure.Name = 'BeltApp';
            app.BeltAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @BeltAppUIFigureCloseRequest, true);
            app.BeltAppUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.BeltAppUIFigure.WindowButtonUpFcn = createCallbackFcn(app, @BeltAppUIFigureWindowButtonUp, true);
            app.BeltAppUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @BeltAppUIFigureWindowButtonMotion, true);
            app.BeltAppUIFigure.ButtonDownFcn = createCallbackFcn(app, @BeltAppUIFigureButtonDown, true);
            app.Toolbar = uitoolbar(app.BeltAppUIFigure);
            app.AddButton = uipushtool(app.Toolbar);
            app.AddButton.Tooltip = {'Use the brush tool to select a range of datapoints, then press this to add a new artifact'};
            app.AddButton.ClickedCallback = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Icon = fullfile(pathToMLAPP, 'icons', 'add.png');
            app.DeleteEntryButton = uipushtool(app.Toolbar);
            app.DeleteEntryButton.Tooltip = {'Select artifact(s) & click to remove these'};
            app.DeleteEntryButton.ClickedCallback = createCallbackFcn(app, @DeleteEntryButtonPushed, true);
            app.DeleteEntryButton.Icon = fullfile(pathToMLAPP, 'icons', 'remove.png');
            app.MergeButton = uipushtool(app.Toolbar);
            app.MergeButton.Tooltip = {'Merge the selected artifacts to one big artifact, using the outer most start & end time'};
            app.MergeButton.ClickedCallback = createCallbackFcn(app, @MergeButtonPushed, true);
            app.MergeButton.Icon = fullfile(pathToMLAPP, 'icons', 'combine.png');
            app.UndoButton = uipushtool(app.Toolbar);
            app.UndoButton.ClickedCallback = createCallbackFcn(app, @UndoButtonClicked, true);
            app.UndoButton.Icon = fullfile(pathToMLAPP, 'icons', 'undo.png');
            app.GridLayout = uigridlayout(app.BeltAppUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.SolutionOptionsPanel = uipanel(app.GridLayout3);
            app.SolutionOptionsPanel.Title = 'Solution Options';
            app.customvalueEditFieldLabel = uilabel(app.SolutionOptionsPanel);
            app.customvalueEditFieldLabel.Text = 'custom value';
            app.SolutionsCustomValue = uieditfield(app.SolutionOptionsPanel, 'numeric');
            app.SolutionsCustomValue.ValueChangedFcn = createCallbackFcn(app, @SolutionsCustomValueValueChanged, true);
            app.SolutionLabel = uilabel(app.SolutionOptionsPanel);
            app.SolutionLabel.Text = 'method';
            app.SolutionsDropDown = uidropdown(app.SolutionOptionsPanel);
            app.SolutionsDropDown.Items = {'linear', 'spline', 'NaN', 'startpos', 'endpos', 'custom'};
            app.SolutionsDropDown.ValueChangedFcn = createCallbackFcn(app, @SolutionsDropDownValueChanged, true);
            app.SolutionsDropDown.Value = 'linear';
            app.ConclusionPanel = uipanel(app.GridLayout3);
            app.ConclusionPanel.Title = 'Conclusion';
            app.CancelButton = uibutton(app.ConclusionPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.AcceptButton = uibutton(app.ConclusionPanel, 'push');
            app.AcceptButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptButtonPushed, true);
            app.AcceptButton.Text = 'Accept';
            app.ArtifactDurationPanel = uipanel(app.GridLayout3);
            app.ArtifactDurationPanel.Title = 'Artifact Duration';
            app.Select2artifactstomergeontheouterstartendLabel_2 = uilabel(app.ArtifactDurationPanel);
            app.Select2artifactstomergeontheouterstartendLabel_2.Text = 'Select 1 artifact, and change the start / end value to change its duration';
            app.StartEditFieldLabel = uilabel(app.ArtifactDurationPanel);
            app.StartEditFieldLabel.Text = 'Start';
            app.ArtifactStartValue = uieditfield(app.ArtifactDurationPanel, 'numeric');
            app.ArtifactStartValue.ValueChangedFcn = createCallbackFcn(app, @ArtifactStartValueValueChanged, true);
            app.EndEditFieldLabel = uilabel(app.ArtifactDurationPanel);
            app.EndEditFieldLabel.Text = 'End';
            app.ArtifactEndValue = uieditfield(app.ArtifactDurationPanel, 'numeric');
            app.ArtifactEndValue.ValueChangedFcn = createCallbackFcn(app, @ArtifactEndValueValueChanged, true);
            app.ShowtimevalueatcursorCheckBox = uicheckbox(app.ArtifactDurationPanel);
            app.ShowtimevalueatcursorCheckBox.Text = 'Show time value at cursor';
            app.TimeTooltip = uitextarea(app.ArtifactDurationPanel);
            app.TimeTooltip.Visible = 'off';
            app.TimeTooltip.Value = {'t=0.00'};
            app.Label = uilabel(app.ArtifactDurationPanel);
            app.Label.Text = '';
            app.InfoPanel = uipanel(app.GridLayout3);
            app.InfoPanel.Title = 'Info';
            app.ArtifactsLabel = uilabel(app.InfoPanel);
            app.ArtifactsLabel.Text = 'Artifacts:';
            app.ArtifactLabel = uilabel(app.InfoPanel);
            app.ArtifactLabel.Text = '0';
            app.SelectedArtifactsLabel = uilabel(app.InfoPanel);
            app.SelectedArtifactsLabel.Text = 'Selected Artifacts:';
            app.SelectedLabel = uilabel(app.InfoPanel);
            app.SelectedLabel.Text = '0';
            app.PreviousButton = uibutton(app.InfoPanel, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = '<';
            app.NextButton = uibutton(app.InfoPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = '>';
            app.FocusButton = uibutton(app.InfoPanel, 'push');
            app.FocusButton.ButtonPushedFcn = createCallbackFcn(app, @FocusButtonPushed, true);
            app.FocusButton.Text = 'Focus';
            app.SelectedListLabel = uilabel(app.InfoPanel);
            app.SelectedListLabel.Text = '0';
            app.AllButton = uibutton(app.InfoPanel, 'push');
            app.AllButton.ButtonPushedFcn = createCallbackFcn(app, @AllButtonPushed, true);
            app.AllButton.Text = 'All';
            app.ARTIFACTORNOARTIFACTPanel = uipanel(app.GridLayout3);
            app.ARTIFACTORNOARTIFACTPanel.Title = 'ARTIFACT OR NO ARTIFACT';
            app.ArtifactNoButton = uibutton(app.ARTIFACTORNOARTIFACTPanel, 'push');
            app.ArtifactNoButton.ButtonPushedFcn = createCallbackFcn(app, @ArtifactNoButtonPushed, true);
            app.ArtifactNoButton.Icon = fullfile(pathToMLAPP, 'icons', 'thumbup.png');
            app.ArtifactNoButton.Text = 'NO ARTIFACT';
            app.ArtifactYesButton = uibutton(app.ARTIFACTORNOARTIFACTPanel, 'push');
            app.ArtifactYesButton.ButtonPushedFcn = createCallbackFcn(app, @ArtifactYesButtonPushed, true);
            app.ArtifactYesButton.Icon = fullfile(pathToMLAPP, 'icons', 'thumbdown.png');
            app.ArtifactYesButton.Text = 'ARTIFACT';
            app.TimeScrubberPanel = uipanel(app.GridLayout3);
            app.TimeScrubberPanel.Enable = 'off';
            app.TimeScrubberPanel.Title = 'Time Scrubber';
            app.secEditFieldLabel = uilabel(app.TimeScrubberPanel);
            app.secEditFieldLabel.Text = 'sec.';
            app.TimeScrubberValue = uieditfield(app.TimeScrubberPanel, 'text');
            app.TimeScrubberValue.Value = '10';
            app.TimeScrubberMinus = uibutton(app.TimeScrubberPanel, 'push');
            app.TimeScrubberMinus.ButtonPushedFcn = createCallbackFcn(app, @TimeScrubberMinusButtonPushed, true);
            app.TimeScrubberMinus.Text = '-';
            app.TimeScrubberPlus = uibutton(app.TimeScrubberPanel, 'push');
            app.TimeScrubberPlus.ButtonPushedFcn = createCallbackFcn(app, @TimeScrubberPlusPushed, true);
            app.TimeScrubberPlus.Text = '+';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.ArtefactValidationAxes = uiaxes(app.GridLayout2);
            title(app.ArtefactValidationAxes, 'Artefact Validation')
            xlabel(app.ArtefactValidationAxes, 'X')
            ylabel(app.ArtefactValidationAxes, 'Y')
            zlabel(app.ArtefactValidationAxes, 'Z')
            app.ArtefactValidationAxes.ButtonDownFcn = createCallbackFcn(app, @ArtefactValidationAxesButtonDown, true);
            app.SolutionAxes = uiaxes(app.GridLayout2);
            title(app.SolutionAxes, 'Solution')
            xlabel(app.SolutionAxes, 'X')
            ylabel(app.SolutionAxes, 'Y')
            zlabel(app.SolutionAxes, 'Z')
            app.OriginalDataAxes = uiaxes(app.GridLayout2);
            title(app.OriginalDataAxes, 'Original Data')
            xlabel(app.OriginalDataAxes, 'X')
            ylabel(app.OriginalDataAxes, 'Y')
            zlabel(app.OriginalDataAxes, 'Z')
            app.CurrentStateLabel = uilabel(app.GridLayout2);
            app.CurrentStateLabel.Text = 'All Data';
            % Show the figure after all components are created
            app.BeltAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BeltApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BeltAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BeltAppUIFigure)
        end
    end
end